classdef ResultadosArritmias < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RitmoSinusalNormalLabel      matlab.ui.control.Label
        BradicardiaSinusalLabel      matlab.ui.control.Label
        FibrilacinAuricularLabel     matlab.ui.control.Label
        AleteoAuricularLabel         matlab.ui.control.Label
        TaquicardiaVentricularLabel  matlab.ui.control.Label
        FlutterVentricularLabel      matlab.ui.control.Label
        PRmsLabel                    matlab.ui.control.Label
        QRSmsLabel                   matlab.ui.control.Label
        RitmocardiacoLabel           matlab.ui.control.Label
        OndaPLabel                   matlab.ui.control.Label
        RitmoregularLabel            matlab.ui.control.Label
        selectorPRmsRSN              matlab.ui.control.DropDown
        menorPRmsRSN                 matlab.ui.control.EditField
        mayorPRmsRSN                 matlab.ui.control.EditField
        selectorQRSmsRSN             matlab.ui.control.DropDown
        menorQRSmsRSN                matlab.ui.control.EditField
        mayorQRSmsRSN                matlab.ui.control.EditField
        selectorRitmoCardiacoRSN     matlab.ui.control.DropDown
        menorRitmoCardiacoRSN        matlab.ui.control.EditField
        mayorRitmoCardiacoRSN        matlab.ui.control.EditField
        selectorOndaPRSN             matlab.ui.control.DropDown
        selectorRitmoRegularRSN      matlab.ui.control.DropDown
        selectorPRmsBS               matlab.ui.control.DropDown
        menorPRmsBS                  matlab.ui.control.EditField
        mayorPRmsBS                  matlab.ui.control.EditField
        selectorQRSmsBS              matlab.ui.control.DropDown
        menorQRSmsBS                 matlab.ui.control.EditField
        mayorQRSmsBS                 matlab.ui.control.EditField
        selectorRitmoCardiacoBS      matlab.ui.control.DropDown
        menorRitmoCardiacoBS         matlab.ui.control.EditField
        mayorRitmoCardiacoBS         matlab.ui.control.EditField
        selectorOndaPBS              matlab.ui.control.DropDown
        selectorRitmoRegularBS       matlab.ui.control.DropDown
        selectorPRmsFibAur           matlab.ui.control.DropDown
        menorPRmsFibAur              matlab.ui.control.EditField
        mayorPRmsFibAur              matlab.ui.control.EditField
        selectorQRSmsFibAur          matlab.ui.control.DropDown
        menorQRSmsFibAur             matlab.ui.control.EditField
        mayorQRSmsFibAur             matlab.ui.control.EditField
        selectorRitmoCardiacoFibAur  matlab.ui.control.DropDown
        menorRitmoCardiacoFibAur     matlab.ui.control.EditField
        mayorRitmoCardiacoFibAur     matlab.ui.control.EditField
        selectorOndaPFibAur          matlab.ui.control.DropDown
        selectorRitmoRegularFibAur   matlab.ui.control.DropDown
        selectorPRmsAleAur           matlab.ui.control.DropDown
        menorPRmsAleAur              matlab.ui.control.EditField
        mayorPRmsAleAur              matlab.ui.control.EditField
        selectorQRSmsAleAur          matlab.ui.control.DropDown
        menorQRSmsAleAur             matlab.ui.control.EditField
        mayorQRSmsAleAur             matlab.ui.control.EditField
        selectorRitmoCardiacoAleAur  matlab.ui.control.DropDown
        menorRitmoCardiacoAleAur     matlab.ui.control.EditField
        mayorRitmoCardiacoAleAur     matlab.ui.control.EditField
        selectorOndaPAleAur          matlab.ui.control.DropDown
        selectorRitmoRegularAleAur   matlab.ui.control.DropDown
        selectorPRmsTaVe             matlab.ui.control.DropDown
        menorPRmsTaVe                matlab.ui.control.EditField
        mayorPRmsTaVe                matlab.ui.control.EditField
        selectorQRSmsTaVe            matlab.ui.control.DropDown
        menorQRSmsTaVe               matlab.ui.control.EditField
        mayorQRSmsTaVe               matlab.ui.control.EditField
        selectorRitmoCardiacoTaVe    matlab.ui.control.DropDown
        menorRitmoCardiacoTaVe       matlab.ui.control.EditField
        mayorRitmoCardiacoTaVe       matlab.ui.control.EditField
        selectorOndaPTaVe            matlab.ui.control.DropDown
        selectorRitmoRegularTaVe     matlab.ui.control.DropDown
        selectorPRmsFluVe            matlab.ui.control.DropDown
        menorPRmsFluVe               matlab.ui.control.EditField
        mayorPRmsFluVe               matlab.ui.control.EditField
        selectorQRSmsFluVe           matlab.ui.control.DropDown
        menorQRSmsFluVe              matlab.ui.control.EditField
        mayorQRSmsFluVe              matlab.ui.control.EditField
        selectorRitmoCardiacoFluVe   matlab.ui.control.DropDown
        menorRitmoCardiacoFluVe      matlab.ui.control.EditField
        mayorRitmoCardiacoFluVe      matlab.ui.control.EditField
        selectorOndaPFluVe           matlab.ui.control.DropDown
        selectorRitmoRegularFluVe    matlab.ui.control.DropDown
        AnalizarButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: selectorPRmsRSN
        function selectorPRmsRSNValueChanged(app, event)
            global valueSelectorPRmsRSN;
            valueSelectorPRmsRSN = app.selectorPRmsRSN.Value;
            if(convertCharsToStrings(valueSelectorPRmsRSN) == 'Entre')
                app.menorPRmsRSN.Visible = true;
                app.mayorPRmsRSN.Visible = true;
            elseif(convertCharsToStrings(valueSelectorPRmsRSN) == 'Mayor')
                app.menorPRmsRSN.Visible = false;
                app.mayorPRmsRSN.Visible = true;
            elseif(convertCharsToStrings(valueSelectorPRmsRSN) == 'Menor')
                app.menorPRmsRSN.Visible = true;
                app.mayorPRmsRSN.Visible = false;
            elseif(convertCharsToStrings(valueSelectorPRmsRSN) == 'Ninguno')
                app.menorPRmsRSN.Visible = false;
                app.mayorPRmsRSN.Visible = false;
            end
        end

        % Value changed function: selectorQRSmsRSN
        function selectorQRSmsRSNValueChanged(app, event)
            global valueselectorQRSmsRSN;
            valueselectorQRSmsRSN = app.selectorQRSmsRSN.Value;
            if(convertCharsToStrings(valueselectorQRSmsRSN) == 'Entre')
                app.menorQRSmsRSN.Visible = true;
                app.mayorQRSmsRSN.Visible = true;
            elseif(convertCharsToStrings(valueselectorQRSmsRSN) == 'Mayor')
                app.menorQRSmsRSN.Visible = false;
                app.mayorQRSmsRSN.Visible = true;
            elseif(convertCharsToStrings(valueselectorQRSmsRSN) == 'Menor')
                app.menorQRSmsRSN.Visible = true;
                app.mayorQRSmsRSN.Visible = false;
            elseif(convertCharsToStrings(valueselectorQRSmsRSN) == 'Ninguno')
                app.menorQRSmsRSN.Visible = false;
                app.mayorQRSmsRSN.Visible = false;
            end
        end

        % Value changed function: selectorRitmoCardiacoRSN
        function selectorRitmoCardiacoRSNValueChanged(app, event)
            global valueselectorRitmoCardiacoRSN;
            valueselectorRitmoCardiacoRSN = app.selectorRitmoCardiacoRSN.Value;
            if(convertCharsToStrings(valueselectorRitmoCardiacoRSN) == 'Entre')
                app.menorRitmoCardiacoRSN.Visible = true;
                app.mayorRitmoCardiacoRSN.Visible = true;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoRSN) == 'Mayor')
                app.menorRitmoCardiacoRSN.Visible = false;
                app.mayorRitmoCardiacoRSN.Visible = true;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoRSN) == 'Menor')
                app.menorRitmoCardiacoRSN.Visible = true;
                app.mayorRitmoCardiacoRSN.Visible = false;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoRSN) == 'Ninguno')
                app.menorRitmoCardiacoRSN.Visible = false;
                app.mayorRitmoCardiacoRSN.Visible = false;
            end
        end

        % Button pushed function: AnalizarButton
        function AnalizarButtonPushed(app, event)
            global valueSelectorPRmsRSN;
            global valueselectorQRSmsRSN;
            global valueselectorRitmoCardiacoRSN;
            global valueselectorOndaPRSN;
            global valueselectorRitmoRegularRSN;
            global valueselectorPRmsBS;
            global valueselectorQRSmsBS;
            global valueselectorRitmoCardiacoBS;
            global valueselectorOndaPBS;
            global valueselectorRitmoRegularBS;
            global selectorPRmsFibAur;
            global valueselectorQRSmsFibAur;
            global valueselectorRitmoCardiacoFibAur;
            global valueselectorOndaPFibAur;
            global valueselectorRitmoRegularFibAur;
            global valueselectorPRmsAleAur;
            global valueselectorQRSmsAleAur;
            global valueselectorRitmoCardiacoAleAur;
            global valueselectorOndaPAleAur;
            global valueselectorRitmoRegularAleAur;
            global valueselectorPRmsTaVe;
            global valueselectorQRSmsTaVe;
            global valueselectorRitmoCardiacoTaVe;
            global valueselectorOndaPTaVe;
            global valueselectorRitmoRegularTaVe;
            global valueselectorPRmsFluVe;
            global valueselectorQRSmsFluVe;
            global valueselectorRitmoCardiacoFluVe;
            global valueselectorOndaPFluVe;
            global valueselectorRitmoRegularFluVe;
            if(isempty(valueSelectorPRmsRSN))
                disp('Esta vacio valueSelectorPRmsRSN');
                valueSelectorPRmsRSN = 'Entre';
                disp(valueSelectorPRmsRSN);
            else
                disp('No esta vacio valueSelectorPRmsRSN');
                disp(valueSelectorPRmsRSN);
            end
            if(isempty(valueselectorQRSmsRSN))
                disp('Esta vacio valueselectorQRSmsRSN');
                valueselectorQRSmsRSN = 'Entre';
                disp(valueselectorQRSmsRSN)
            else
                disp('No esta vacio valueselectorQRSmsRSN');
                disp(valueselectorQRSmsRSN);
            end
            if(isempty(valueselectorRitmoCardiacoRSN))
                disp('Esta vacio valueselectorRitmoCardiacoRSN');
                valueselectorRitmoCardiacoRSN = 'Entre';
                disp(valueselectorRitmoCardiacoRSN)
            else
                disp('No esta vacio valueselectorRitmoCardiacoRSN');
                disp(valueselectorRitmoCardiacoRSN);
            end
            
            if(isempty(valueselectorOndaPRSN))
                disp('Esta vacio valueselectorOndaPRSN');
                valueselectorOndaPRSN = '1';
                disp(valueselectorOndaPRSN);
            else
                disp('No esta vacio valueselectorOndaPRSN');
                disp(valueselectorOndaPRSN);
            end
            if(isempty(valueselectorRitmoRegularRSN))
                disp('Esta vacio valueSelector');
                valueselectorRitmoRegularRSN = 'Regular';
                disp(valueselectorRitmoRegularRSN);
            else
                disp('No esta vacio valueSelector');
                disp(valueselectorRitmoRegularRSN);
            end
            
            if(isempty(valueselectorPRmsBS))
                disp('Esta vacio valueselectorPRmsBS');
                valueselectorPRmsBS = 'Entre';
                disp(valueselectorPRmsBS);
            else
                disp('No esta vacio valueselectorPRmsBS');
                disp(valueselectorPRmsBS);
            end
            if(isempty(valueselectorQRSmsBS))
                disp('Esta vacio valueselectorQRSmsBS');
                valueselectorQRSmsBS = 'Entre';
                disp(valueselectorQRSmsBS);
            else
                disp('No esta vacio valueselectorQRSmsBS');
                disp(valueselectorQRSmsBS);
            end
            
            if(isempty(valueselectorRitmoCardiacoBS))
                disp('Esta vacio valueselectorRitmoCardiacoBS');
                valueselectorRitmoCardiacoBS = 'Entre';
                disp(valueselectorRitmoCardiacoBS);
            else
                disp('No esta vacio valueselectorRitmoCardiacoBS');
                disp(valueselectorRitmoCardiacoBS);
            end
            if(isempty(valueselectorOndaPBS))
                disp('Esta vacio valueselectorOndaPBS');
                valueselectorOndaPBS = '1';
                disp(valueselectorOndaPBS);
            else
                disp('No esta vacio valueselectorOndaPBS');
                disp(valueselectorOndaPBS);
            end
            if(isempty(valueselectorRitmoRegularBS))
                disp('Esta vacio valueselectorRitmoRegularBS');
                valueselectorRitmoRegularBS = 'Regular';
                disp(valueselectorRitmoRegularBS);
            else
                disp('No esta vacio valueselectorRitmoRegularBS');
                disp(valueselectorRitmoRegularBS);
            end
            if(isempty(selectorPRmsFibAur))
                disp('Esta vacio selectorPRmsFibAur');
                selectorPRmsFibAur = 'Entre';
                disp(selectorPRmsFibAur);
            else
                disp('No esta vacio selectorPRmsFibAur');
                disp(selectorPRmsFibAur);
            end
            if(isempty(valueselectorQRSmsFibAur))
                disp('Esta vacio valueselectorQRSmsFibAur');
                valueselectorQRSmsFibAur = 'Entre';
                disp(valueselectorQRSmsFibAur);
            else
                disp('No esta vacio valueselectorQRSmsFibAur');
                disp(valueselectorQRSmsFibAur);
            end
            if(isempty(valueselectorRitmoCardiacoFibAur))
                disp('Esta vacio valueselectorRitmoCardiacoFibAur');
                valueselectorRitmoCardiacoFibAur = 'Entre';
                disp(valueselectorRitmoCardiacoFibAur);
            else
                disp('No esta vacio valueselectorRitmoCardiacoFibAur');
                disp(valueselectorRitmoCardiacoFibAur);
            end
            
            if(isempty(valueselectorOndaPFibAur))
                disp('Esta vacio valueselectorOndaPFibAur');
                valueselectorOndaPFibAur = '1';
                disp(valueselectorOndaPFibAur);
            else
                disp('No esta vacio valueselectorOndaPFibAur');
                disp(valueselectorOndaPFibAur);
            end
            
            if(isempty(valueselectorRitmoRegularFibAur))
                disp('Esta vacio valueselectorRitmoRegularFibAur');
                valueselectorRitmoRegularFibAur = 'Regular';
                disp(valueselectorRitmoRegularFibAur);
            else
                disp('No esta vacio valueselectorRitmoRegularFibAur');
                disp(valueselectorRitmoRegularFibAur);
            end
           
            if(isempty(valueselectorPRmsAleAur))
                disp('Esta vacio valueselectorPRmsAleAur');
                valueselectorPRmsAleAur = 'Entre';
                disp(valueselectorPRmsAleAur);
            else
                disp('No esta vacio valueselectorPRmsAleAur');
                disp(valueselectorPRmsAleAur);
            end
            
            if(isempty(valueselectorQRSmsAleAur))
                disp('Esta vacio valueselectorQRSmsAleAur');
                valueselectorQRSmsAleAur = 'Entre';
                disp(valueselectorQRSmsAleAur);
            else
                disp('No esta vacio valueselectorQRSmsAleAur');
                disp(valueselectorQRSmsAleAur);
            end
            
            if(isempty(valueselectorRitmoCardiacoAleAur))
                disp('Esta vacio valueselectorRitmoCardiacoAleAur');
                valueselectorRitmoCardiacoAleAur = 'Entre';
                disp(valueselectorRitmoCardiacoAleAur);
            else
                disp('No esta vacio valueselectorRitmoCardiacoAleAur');
                disp(valueselectorRitmoCardiacoAleAur);
            end
            
            if(isempty(valueselectorOndaPAleAur))
                disp('Esta vacio valueselectorOndaPAleAur');
                valueselectorOndaPAleAur = '1';
                disp(valueselectorOndaPAleAur);
            else
                disp('No esta vacio valueselectorOndaPAleAur');
                disp(valueselectorOndaPAleAur);
            end
            
            if(isempty(valueselectorRitmoRegularAleAur))
                disp('Esta vacio valueselectorRitmoRegularAleAur');
                valueselectorRitmoRegularAleAur = 'Regular';
                disp(valueselectorRitmoRegularAleAur);
            else
                disp('No esta vacio valueselectorRitmoRegularAleAur');
                disp(valueselectorRitmoRegularAleAur);
            end
            
            
            if(isempty(valueselectorPRmsTaVe))
                disp('Esta vacio valueselectorPRmsTaVe');
                valueselectorPRmsTaVe = 'Entre';
                disp(valueselectorPRmsTaVe);
            else
                disp('No esta vacio valueselectorPRmsTaVe');
                disp(valueselectorPRmsTaVe);
            end
            
            if(isempty(valueselectorQRSmsTaVe))
                disp('Esta vacio valueselectorQRSmsTaVe');
                valueselectorQRSmsTaVe = 'Entre';
                disp(valueselectorQRSmsTaVe);
            else
                disp('No esta vacio valueselectorQRSmsTaVe');
                disp(valueselectorQRSmsTaVe);
            end
            if(isempty(valueselectorRitmoCardiacoTaVe))
                disp('Esta vacio valueselectorRitmoCardiacoTaVe');
                valueselectorRitmoCardiacoTaVe = 'Entre';
                disp(valueselectorRitmoCardiacoTaVe);
            else
                disp('No esta vacio valueselectorRitmoCardiacoTaVe');
                disp(valueselectorRitmoCardiacoTaVe);
            end
            
            if(isempty(valueselectorOndaPTaVe))
                disp('Esta vacio valueselectorOndaPTaVe');
                valueselectorOndaPTaVe = '1';
                disp(valueselectorOndaPTaVe);
            else
                disp('No esta vacio valueselectorOndaPTaVe');
                disp(valueselectorOndaPTaVe);
            end
            
            if(isempty(valueselectorRitmoRegularTaVe))
                disp('Esta vacio valueselectorRitmoRegularTaVe');
                valueselectorRitmoRegularTaVe = 'Regular';
                disp(valueselectorRitmoRegularTaVe);
            else
                disp('No esta vacio valueselectorRitmoRegularTaVe');
                disp(valueselectorRitmoRegularTaVe);
            end
            
            if(isempty(valueselectorPRmsFluVe))
                disp('Esta vacio valueselectorPRmsFluVe');
                valueselectorPRmsFluVe = 'Entre';
                disp(valueselectorPRmsFluVe);
            else
                disp('No esta vacio valueselectorPRmsFluVe');
                disp(valueselectorPRmsFluVe);
            end
            if(isempty(valueselectorQRSmsFluVe))
                disp('Esta vacio valueselectorQRSmsFluVe');
                valueselectorQRSmsFluVe = 'Entre';
                disp(valueselectorQRSmsFluVe);
            else
                disp('No esta vacio valueselectorQRSmsFluVe');
                disp(valueselectorQRSmsFluVe);
            end
            if(isempty(valueselectorRitmoCardiacoFluVe))
                disp('Esta vacio valueselectorRitmoCardiacoFluVe');
                valueselectorRitmoCardiacoFluVe = 'Entre';
                disp(valueselectorRitmoCardiacoFluVe);
            else
                disp('No esta vacio valueselectorRitmoCardiacoFluVe');
                disp(valueselectorRitmoCardiacoFluVe);
            end
            if(isempty(valueselectorOndaPFluVe))
                disp('Esta vacio valueselectorOndaPFluVe');
                valueselectorOndaPFluVe = '1';
                disp(valueselectorOndaPFluVe);
            else
                disp('No esta vacio valueselectorOndaPFluVe');
                disp(valueselectorOndaPFluVe);
            end
            
            if(isempty(valueselectorRitmoRegularFluVe))
                disp('Esta vacio valueselectorRitmoRegularFluVe');
                valueselectorRitmoRegularFluVe = 'Regular';
                disp(valueselectorRitmoRegularFluVe);
            else
                disp('No esta vacio valueselectorRitmoRegularFluVe');
                disp(valueselectorRitmoRegularFluVe);
            end
            %%Ritmo Sinusal Normal PR
            load(strcat('ResultadosAnotacion/','resultadosTodo'));
            load(strcat('ResultadosAnotacion/','resultadosTodoConNumero'));
            assignin('base','resultadosTodo',resultadosTodo);
            assignin('base','resultadosTodoConNumero',resultadosTodoConNumero);
            
            if(convertCharsToStrings(valueSelectorPRmsRSN) == 'Entre')
                z = 1;
                o = 1;
                for i=1:length(resultadosTodoConNumero)
                     if resultadosTodoConNumero{i,5} >= str2num(app.menorPRmsRSN.Value) && resultadosTodoConNumero{i,5} <= str2num(app.mayorPRmsRSN.Value)
                         PRmsRSN{z,1} = resultadosTodoConNumero{i,1};
                         PRmsRSN{z,2} = resultadosTodoConNumero{i,2};
                         PRmsRSN{z,3} = resultadosTodoConNumero{i,3};
                         PRmsRSN{z,4} = resultadosTodoConNumero{i,4};
                         PRmsRSN{z,5} = resultadosTodoConNumero{i,5};
                         PRmsRSN{z,6} = resultadosTodoConNumero{i,6};
                         PRmsRSN{z,7} = resultadosTodoConNumero{i,7};
                         PRmsRSN{z,8} = resultadosTodoConNumero{i,8};
                         PRmsRSN{z,9} = resultadosTodoConNumero{i,9};
                         PRmsRSN{z,10} = resultadosTodoConNumero{i,10};
                         z = z + 1;
                     else
                         PRmsRSNNo{o,1} = resultadosTodoConNumero{i,1};
                         PRmsRSNNo{o,2} = resultadosTodoConNumero{i,2};
                         PRmsRSNNo{o,3} = resultadosTodoConNumero{i,3};
                         PRmsRSNNo{o,4} = resultadosTodoConNumero{i,4};
                         PRmsRSNNo{o,5} = resultadosTodoConNumero{i,5};
                         PRmsRSNNo{o,6} = resultadosTodoConNumero{i,6};
                         PRmsRSNNo{o,7} = resultadosTodoConNumero{i,7};
                         PRmsRSNNo{o,8} = resultadosTodoConNumero{i,8};
                         PRmsRSNNo{o,9} = resultadosTodoConNumero{i,9};
                         PRmsRSNNo{o,10} = resultadosTodoConNumero{i,10};
                         o = o + 1;
                     end
                end
                assignin('base','PRmsRSN',PRmsRSN);
                assignin('base','PRmsRSNNo',PRmsRSNNo);
            elseif(convertCharsToStrings(valueSelectorPRmsRSN) == 'Mayor')
                z = 1;
                o = 1;
                for i=1:length(resultadosTodoConNumero)
                     if resultadosTodoConNumero{i,5} <= str2num(app.mayorPRmsRSN.Value)
                         PRmsRSN{z,1} = resultadosTodoConNumero{i,1};
                         PRmsRSN{z,2} = resultadosTodoConNumero{i,2};
                         PRmsRSN{z,3} = resultadosTodoConNumero{i,3};
                         PRmsRSN{z,4} = resultadosTodoConNumero{i,4};
                         PRmsRSN{z,5} = resultadosTodoConNumero{i,5};
                         PRmsRSN{z,6} = resultadosTodoConNumero{i,6};
                         PRmsRSN{z,7} = resultadosTodoConNumero{i,7};
                         PRmsRSN{z,8} = resultadosTodoConNumero{i,8};
                         PRmsRSN{z,9} = resultadosTodoConNumero{i,9};
                         PRmsRSN{z,10} = resultadosTodoConNumero{i,10};
                         z = z + 1;
                     else
                         PRmsRSNNo{o,1} = resultadosTodoConNumero{i,1};
                         PRmsRSNNo{o,2} = resultadosTodoConNumero{i,2};
                         PRmsRSNNo{o,3} = resultadosTodoConNumero{i,3};
                         PRmsRSNNo{o,4} = resultadosTodoConNumero{i,4};
                         PRmsRSNNo{o,5} = resultadosTodoConNumero{i,5};
                         PRmsRSNNo{o,6} = resultadosTodoConNumero{i,6};
                         PRmsRSNNo{o,7} = resultadosTodoConNumero{i,7};
                         PRmsRSNNo{o,8} = resultadosTodoConNumero{i,8};
                         PRmsRSNNo{o,9} = resultadosTodoConNumero{i,9};
                         PRmsRSNNo{o,10} = resultadosTodoConNumero{i,10};
                         o = o + 1;
                     end
                end
                assignin('base','PRmsRSN',PRmsRSN);
                assignin('base','PRmsRSNNo',PRmsRSNNo);
            elseif(convertCharsToStrings(valueSelectorPRmsRSN) == 'Menor')
                z = 1;
                o = 1;
                for i=1:length(resultadosTodoConNumero)
                     if resultadosTodoConNumero{i,5} >= str2num(app.menorPRmsRSN.Value)
                         PRmsRSN{z,1} = resultadosTodoConNumero{i,1};
                         PRmsRSN{z,2} = resultadosTodoConNumero{i,2};
                         PRmsRSN{z,3} = resultadosTodoConNumero{i,3};
                         PRmsRSN{z,4} = resultadosTodoConNumero{i,4};
                         PRmsRSN{z,5} = resultadosTodoConNumero{i,5};
                         PRmsRSN{z,6} = resultadosTodoConNumero{i,6};
                         PRmsRSN{z,7} = resultadosTodoConNumero{i,7};
                         PRmsRSN{z,8} = resultadosTodoConNumero{i,8};
                         PRmsRSN{z,9} = resultadosTodoConNumero{i,9};
                         PRmsRSN{z,10} = resultadosTodoConNumero{i,10};
                         z = z + 1;
                     else
                         PRmsRSNNo{o,1} = resultadosTodoConNumero{i,1};
                         PRmsRSNNo{o,2} = resultadosTodoConNumero{i,2};
                         PRmsRSNNo{o,3} = resultadosTodoConNumero{i,3};
                         PRmsRSNNo{o,4} = resultadosTodoConNumero{i,4};
                         PRmsRSNNo{o,5} = resultadosTodoConNumero{i,5};
                         PRmsRSNNo{o,6} = resultadosTodoConNumero{i,6};
                         PRmsRSNNo{o,7} = resultadosTodoConNumero{i,7};
                         PRmsRSNNo{o,8} = resultadosTodoConNumero{i,8};
                         PRmsRSNNo{o,9} = resultadosTodoConNumero{i,9};
                         PRmsRSNNo{o,10} = resultadosTodoConNumero{i,10};
                         o = o + 1;
                     end
                end
                assignin('base','PRmsRSN',PRmsRSN);
                assignin('base','PRmsRSNNo',PRmsRSNNo);
            elseif(convertCharsToStrings(valueSelectorPRmsRSN) == 'Ninguno')
                z = 1;
                o = 1;
                for i=1:length(resultadosTodoConNumero)   
                         PRmsRSN{z,1} = resultadosTodoConNumero{i,1};
                         PRmsRSN{z,2} = resultadosTodoConNumero{i,2};
                         PRmsRSN{z,3} = resultadosTodoConNumero{i,3};
                         PRmsRSN{z,4} = resultadosTodoConNumero{i,4};
                         PRmsRSN{z,5} = resultadosTodoConNumero{i,5};
                         PRmsRSN{z,6} = resultadosTodoConNumero{i,6};
                         PRmsRSN{z,7} = resultadosTodoConNumero{i,7};
                         PRmsRSN{z,8} = resultadosTodoConNumero{i,8};
                         PRmsRSN{z,9} = resultadosTodoConNumero{i,9};
                         PRmsRSN{z,10} = resultadosTodoConNumero{i,10};
                         z = z + 1;
                end
                PRmsRSNNo = {};
                assignin('base','PRmsRSN',PRmsRSN);
                assignin('base','PRmsRSNNo',PRmsRSNNo);
                
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            disp('Se cerró')
            global valueSelectorPRmsRSN
            global valueselectorQRSmsRSN;
            global valueselectorRitmoCardiacoRSN;
            global valueselectorOndaPRSN;
            global valueselectorRitmoRegularRSN;
            global valueselectorPRmsBS;
            global valueselectorQRSmsBS;
            global valueselectorRitmoCardiacoBS;
            global valueselectorOndaPBS;
            global valueselectorRitmoRegularBS;
            global selectorPRmsFibAur;
            global valueselectorQRSmsFibAur;
            global valueselectorRitmoCardiacoFibAur;
            global valueselectorOndaPFibAur;
            global valueselectorRitmoRegularFibAur;
            global valueselectorPRmsAleAur;
            global valueselectorQRSmsAleAur;
            global valueselectorRitmoCardiacoAleAur;
            global valueselectorOndaPAleAur;
            global valueselectorRitmoRegularAleAur;
            global valueselectorPRmsTaVe;
            global valueselectorQRSmsTaVe;
            global valueselectorRitmoCardiacoTaVe;
            global valueselectorOndaPTaVe;
            global valueselectorRitmoRegularTaVe;
            global valueselectorPRmsFluVe;
            global valueselectorQRSmsFluVe;
            global valueselectorRitmoCardiacoFluVe;
            global valueselectorOndaPFluVe;
            global valueselectorRitmoRegularFluVe;
            valueSelectorPRmsRSN = [];
            valueselectorQRSmsRSN = [];
            valueselectorRitmoCardiacoRSN = [];
            valueselectorOndaPRSN = [];
            valueselectorRitmoRegularRSN = [];
            valueselectorPRmsBS = [];
            valueselectorQRSmsBS = [];
            valueselectorRitmoCardiacoBS = [];
            valueselectorOndaPBS = [];
            valueselectorRitmoRegularBS = [];
            selectorPRmsFibAur = [];
            valueselectorQRSmsFibAur = [];
            valueselectorRitmoCardiacoFibAur = [];
            valueselectorOndaPFibAur = [];
            valueselectorRitmoRegularFibAur = [];
            valueselectorPRmsAleAur = [];
            valueselectorQRSmsAleAur = [];
            valueselectorRitmoCardiacoAleAur = [];
            valueselectorOndaPAleAur = [];
            valueselectorRitmoRegularAleAur = [];
            valueselectorPRmsTaVe = [];
            valueselectorQRSmsTaVe = [];
            valueselectorRitmoCardiacoTaVe = [];
            valueselectorOndaPTaVe = [];
            valueselectorRitmoRegularTaVe = [];
            valueselectorPRmsFluVe = [];
            valueselectorQRSmsFluVe = [];
            valueselectorRitmoCardiacoFluVe = [];
            valueselectorOndaPFluVe = [];
            valueselectorRitmoRegularFluVe = [];
        end

        % Value changed function: selectorOndaPRSN
        function selectorOndaPRSNValueChanged(app, event)
            global valueselectorOndaPRSN;
            valueselectorOndaPRSN = app.selectorOndaPRSN.Value;
            if(convertCharsToStrings(valueselectorOndaPRSN) == '1')
            elseif(convertCharsToStrings(valueselectorOndaPRSN) == '0')   
            elseif(convertCharsToStrings(valueselectorOndaPRSN) == 'Ninguno')
            end

        end

        % Value changed function: selectorRitmoRegularRSN
        function selectorRitmoRegularRSNValueChanged(app, event)
            global valueselectorRitmoRegularRSN;
            valueselectorRitmoRegularRSN = app.selectorRitmoRegularRSN.Value;
            if(convertCharsToStrings(valueselectorRitmoRegularRSN) == 'Regular')
            elseif(convertCharsToStrings(valueselectorRitmoRegularRSN) == 'Irregular')
            elseif(convertCharsToStrings(valueselectorRitmoRegularRSN) == 'Ambos')
            elseif(convertCharsToStrings(valueselectorRitmoRegularRSN) == 'Ninguno')
            end
        end

        % Value changed function: selectorPRmsBS
        function selectorPRmsBSValueChanged(app, event)
            global valueselectorPRmsBS;
            valueselectorPRmsBS = app.selectorPRmsBS.Value;
            if(convertCharsToStrings(valueselectorPRmsBS) == 'Entre')
                app.menorPRmsBS.Visible = true;
                app.mayorPRmsBS.Visible = true;
            elseif(convertCharsToStrings(valueselectorPRmsBS) == 'Mayor')
                app.menorPRmsBS.Visible = false;
                app.mayorPRmsBS.Visible = true;
            elseif(convertCharsToStrings(valueselectorPRmsBS) == 'Menor')
                app.menorPRmsBS.Visible = true;
                app.mayorPRmsBS.Visible = false;
            elseif(convertCharsToStrings(valueselectorPRmsBS) == 'Ninguno')
                app.menorPRmsBS.Visible = false;
                app.mayorPRmsBS.Visible = false;
            end
        end

        % Value changed function: selectorQRSmsBS
        function selectorQRSmsBSValueChanged(app, event)
            global valueselectorQRSmsBS;
            valueselectorQRSmsBS = app.selectorQRSmsBS.Value;
            if(convertCharsToStrings(valueselectorQRSmsBS) == 'Entre')
                app.menorQRSmsBS.Visible = true;
                app.mayorQRSmsBS.Visible = true;
            elseif(convertCharsToStrings(valueselectorQRSmsBS) == 'Mayor')
                app.menorQRSmsBS.Visible = false;
                app.mayorQRSmsBS.Visible = true;
            elseif(convertCharsToStrings(valueselectorQRSmsBS) == 'Menor')
                app.menorQRSmsBS.Visible = true;
                app.mayorQRSmsBS.Visible = false;
            elseif(convertCharsToStrings(valueselectorQRSmsBS) == 'Ninguno')
                app.menorQRSmsBS.Visible = false;
                app.mayorQRSmsBS.Visible = false;
            end
        end

        % Value changed function: selectorRitmoCardiacoBS
        function selectorRitmoCardiacoBSValueChanged(app, event)
            global valueselectorRitmoCardiacoBS;
            valueselectorRitmoCardiacoBS = app.selectorRitmoCardiacoBS.Value;
            if(convertCharsToStrings(valueselectorRitmoCardiacoBS) == 'Entre')
                app.menorRitmoCardiacoBS.Visible = true;
                app.mayorRitmoCardiacoBS.Visible = true;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoBS) == 'Mayor')
                app.menorRitmoCardiacoBS.Visible = false;
                app.mayorRitmoCardiacoBS.Visible = true;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoBS) == 'Menor')
                app.menorRitmoCardiacoBS.Visible = true;
                app.mayorRitmoCardiacoBS.Visible = false;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoBS) == 'Ninguno')
                app.menorRitmoCardiacoBS.Visible = false;
                app.mayorRitmoCardiacoBS.Visible = false;
            end
            
        end

        % Value changed function: selectorOndaPBS
        function selectorOndaPBSValueChanged(app, event)
            global valueselectorOndaPBS;
            valueselectorOndaPBS = app.selectorOndaPBS.Value;
            if(convertCharsToStrings(valueselectorOndaPBS) == '1')
            elseif(convertCharsToStrings(valueselectorOndaPBS) == '0')   
            elseif(convertCharsToStrings(valueselectorOndaPBS) == 'Ninguno')
            end
        end

        % Value changed function: selectorRitmoRegularBS
        function selectorRitmoRegularBSValueChanged(app, event)
            global valueselectorRitmoRegularBS;
            valueselectorRitmoRegularBS = app.selectorRitmoRegularBS.Value;
            if(convertCharsToStrings(valueselectorRitmoRegularBS) == 'Regular')
            elseif(convertCharsToStrings(valueselectorRitmoRegularBS) == 'Irregular')
            elseif(convertCharsToStrings(valueselectorRitmoRegularBS) == 'Ambos')
            elseif(convertCharsToStrings(valueselectorRitmoRegularBS) == 'Ninguno')
            end
        end

        % Value changed function: selectorPRmsFibAur
        function selectorPRmsFibAurValueChanged(app, event)
            global selectorPRmsFibAur;
            selectorPRmsFibAur = app.selectorPRmsFibAur.Value;
            if(convertCharsToStrings(selectorPRmsFibAur) == 'Entre')
                app.menorPRmsFibAur.Visible = true;
                app.mayorPRmsFibAur.Visible = true;
            elseif(convertCharsToStrings(selectorPRmsFibAur) == 'Mayor')
                app.menorPRmsFibAur.Visible = false;
                app.mayorPRmsFibAur.Visible = true;
            elseif(convertCharsToStrings(selectorPRmsFibAur) == 'Menor')
                app.menorPRmsFibAur.Visible = true;
                app.mayorPRmsFibAur.Visible = false;
            elseif(convertCharsToStrings(selectorPRmsFibAur) == 'Ninguno')
                app.menorPRmsFibAur.Visible = false;
                app.mayorPRmsFibAur.Visible = false;
            end
        end

        % Value changed function: selectorQRSmsFibAur
        function selectorQRSmsFibAurValueChanged(app, event)
            global valueselectorQRSmsFibAur;
            valueselectorQRSmsFibAur = app.selectorQRSmsFibAur.Value;
            if(convertCharsToStrings(valueselectorQRSmsFibAur) == 'Entre')
                app.menorQRSmsFibAur.Visible = true;
                app.mayorQRSmsFibAur.Visible = true;
            elseif(convertCharsToStrings(valueselectorQRSmsFibAur) == 'Mayor')
                app.menorQRSmsFibAur.Visible = false;
                app.mayorQRSmsFibAur.Visible = true;
            elseif(convertCharsToStrings(valueselectorQRSmsFibAur) == 'Menor')
                app.menorQRSmsFibAur.Visible = true;
                app.mayorQRSmsFibAur.Visible = false;
            elseif(convertCharsToStrings(valueselectorQRSmsFibAur) == 'Ninguno')
                app.menorQRSmsFibAur.Visible = false;
                app.mayorQRSmsFibAur.Visible = false;
            end
        end

        % Value changed function: selectorRitmoCardiacoFibAur
        function selectorRitmoCardiacoFibAurValueChanged(app, event)
            global valueselectorRitmoCardiacoFibAur;
            valueselectorRitmoCardiacoFibAur = app.selectorRitmoCardiacoFibAur.Value;
            if(convertCharsToStrings(valueselectorRitmoCardiacoFibAur) == 'Entre')
                app.menorRitmoCardiacoFibAur.Visible = true;
                app.mayorRitmoCardiacoFibAur.Visible = true;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoFibAur) == 'Mayor')
                app.menorRitmoCardiacoFibAur.Visible = false;
                app.mayorRitmoCardiacoFibAur.Visible = true;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoFibAur) == 'Menor')
                app.menorRitmoCardiacoFibAur.Visible = true;
                app.mayorRitmoCardiacoFibAur.Visible = false;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoFibAur) == 'Ninguno')
                app.menorRitmoCardiacoFibAur.Visible = false;
                app.mayorRitmoCardiacoFibAur.Visible = false;
            end
        end

        % Value changed function: selectorOndaPFibAur
        function selectorOndaPFibAurValueChanged(app, event)
            global valueselectorOndaPFibAur;
            valueselectorOndaPFibAur = app.selectorOndaPFibAur.Value;
            if(convertCharsToStrings(valueselectorOndaPFibAur) == '1')
            elseif(convertCharsToStrings(valueselectorOndaPFibAur) == '0')   
            elseif(convertCharsToStrings(valueselectorOndaPFibAur) == 'Ninguno')
            end
        end

        % Value changed function: selectorRitmoRegularFibAur
        function selectorRitmoRegularFibAurValueChanged(app, event)
            global valueselectorRitmoRegularFibAur;
            valueselectorRitmoRegularFibAur = app.selectorRitmoRegularFibAur.Value;
            if(convertCharsToStrings(valueselectorRitmoRegularFibAur) == 'Regular')
            elseif(convertCharsToStrings(valueselectorRitmoRegularFibAur) == 'Irregular')
            elseif(convertCharsToStrings(valueselectorRitmoRegularFibAur) == 'Ambos')
            elseif(convertCharsToStrings(valueselectorRitmoRegularFibAur) == 'Ninguno')
            end
        end

        % Value changed function: selectorPRmsAleAur
        function selectorPRmsAleAurValueChanged(app, event)
            global valueselectorPRmsAleAur;
            valueselectorPRmsAleAur = app.selectorPRmsAleAur.Value;
            if(convertCharsToStrings(valueselectorPRmsAleAur) == 'Entre')
                app.menorPRmsAleAur.Visible = true;
                app.mayorPRmsAleAur.Visible = true;
            elseif(convertCharsToStrings(valueselectorPRmsAleAur) == 'Mayor')
                app.menorPRmsAleAur.Visible = false;
                app.mayorPRmsAleAur.Visible = true;
            elseif(convertCharsToStrings(valueselectorPRmsAleAur) == 'Menor')
                app.menorPRmsAleAur.Visible = true;
                app.mayorPRmsAleAur.Visible = false;
            elseif(convertCharsToStrings(valueselectorPRmsAleAur) == 'Ninguno')
                app.menorPRmsAleAur.Visible = false;
                app.mayorPRmsAleAur.Visible = false;
            end
        end

        % Value changed function: selectorQRSmsAleAur
        function selectorQRSmsAleAurValueChanged(app, event)
            global valueselectorQRSmsAleAur;
            valueselectorQRSmsAleAur = app.selectorQRSmsAleAur.Value;
            if(convertCharsToStrings(valueselectorQRSmsAleAur) == 'Entre')
                app.menorQRSmsAleAur.Visible = true;
                app.mayorQRSmsAleAur.Visible = true;
            elseif(convertCharsToStrings(valueselectorQRSmsAleAur) == 'Mayor')
                app.menorQRSmsAleAur.Visible = false;
                app.mayorQRSmsAleAur.Visible = true;
            elseif(convertCharsToStrings(valueselectorQRSmsAleAur) == 'Menor')
                app.menorQRSmsAleAur.Visible = true;
                app.mayorQRSmsAleAur.Visible = false;
            elseif(convertCharsToStrings(valueselectorQRSmsAleAur) == 'Ninguno')
                app.menorQRSmsAleAur.Visible = false;
                app.mayorQRSmsAleAur.Visible = false;
            end
        end

        % Value changed function: selectorRitmoCardiacoAleAur
        function selectorRitmoCardiacoAleAurValueChanged(app, event)
            global valueselectorRitmoCardiacoAleAur;
            valueselectorRitmoCardiacoAleAur = app.selectorRitmoCardiacoAleAur.Value;
            if(convertCharsToStrings(valueselectorRitmoCardiacoAleAur) == 'Entre')
                app.menorRitmoCardiacoAleAur.Visible = true;
                app.mayorRitmoCardiacoAleAur.Visible = true;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoAleAur) == 'Mayor')
                app.menorRitmoCardiacoAleAur.Visible = false;
                app.mayorRitmoCardiacoAleAur.Visible = true;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoAleAur) == 'Menor')
                app.menorRitmoCardiacoAleAur.Visible = true;
                app.mayorRitmoCardiacoAleAur.Visible = false;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoAleAur) == 'Ninguno')
                app.menorRitmoCardiacoAleAur.Visible = false;
                app.mayorRitmoCardiacoAleAur.Visible = false;
            end
        end

        % Value changed function: selectorOndaPAleAur
        function selectorOndaPAleAurValueChanged(app, event)
            global valueselectorOndaPAleAur;
            valueselectorOndaPAleAur = app.selectorOndaPAleAur.Value;
            if(convertCharsToStrings(valueselectorOndaPAleAur) == '1')
            elseif(convertCharsToStrings(valueselectorOndaPAleAur) == '0')   
            elseif(convertCharsToStrings(valueselectorOndaPAleAur) == 'Ninguno')
            end
        end

        % Value changed function: selectorRitmoRegularAleAur
        function selectorRitmoRegularAleAurValueChanged(app, event)
            global valueselectorRitmoRegularAleAur;
            valueselectorRitmoRegularAleAur = app.selectorRitmoRegularAleAur.Value;
            if(convertCharsToStrings(valueselectorRitmoRegularAleAur) == 'Regular')
            elseif(convertCharsToStrings(valueselectorRitmoRegularAleAur) == 'Irregular')
            elseif(convertCharsToStrings(valueselectorRitmoRegularAleAur) == 'Ambos')
            elseif(convertCharsToStrings(valueselectorRitmoRegularAleAur) == 'Ninguno')
            end
        end

        % Value changed function: selectorPRmsTaVe
        function selectorPRmsTaVeValueChanged(app, event)
            global valueselectorPRmsTaVe;
            valueselectorPRmsTaVe = app.selectorPRmsTaVe.Value;
            if(convertCharsToStrings(valueselectorPRmsTaVe) == 'Entre')
                app.menorPRmsTaVe.Visible = true;
                app.mayorPRmsTaVe.Visible = true;
            elseif(convertCharsToStrings(valueselectorPRmsTaVe) == 'Mayor')
                app.menorPRmsTaVe.Visible = false;
                app.mayorPRmsTaVe.Visible = true;
            elseif(convertCharsToStrings(valueselectorPRmsTaVe) == 'Menor')
                app.menorPRmsTaVe.Visible = true;
                app.mayorPRmsTaVe.Visible = false;
            elseif(convertCharsToStrings(valueselectorPRmsTaVe) == 'Ninguno')
                app.menorPRmsTaVe.Visible = false;
                app.mayorPRmsTaVe.Visible = false;
            end
        end

        % Value changed function: selectorQRSmsTaVe
        function selectorQRSmsTaVeValueChanged(app, event)
            global valueselectorQRSmsTaVe;
            valueselectorQRSmsTaVe = app.selectorQRSmsTaVe.Value;
            if(convertCharsToStrings(valueselectorQRSmsTaVe) == 'Entre')
                app.menorQRSmsTaVe.Visible = true;
                app.mayorQRSmsTaVe.Visible = true;
            elseif(convertCharsToStrings(valueselectorQRSmsTaVe) == 'Mayor')
                app.menorQRSmsTaVe.Visible = false;
                app.mayorQRSmsTaVe.Visible = true;
            elseif(convertCharsToStrings(valueselectorQRSmsTaVe) == 'Menor')
                app.menorQRSmsTaVe.Visible = true;
                app.mayorQRSmsTaVe.Visible = false;
            elseif(convertCharsToStrings(valueselectorQRSmsTaVe) == 'Ninguno')
                app.menorQRSmsTaVe.Visible = false;
                app.mayorQRSmsTaVe.Visible = false;
            end
        end

        % Value changed function: selectorRitmoCardiacoTaVe
        function selectorRitmoCardiacoTaVeValueChanged(app, event)
            global valueselectorRitmoCardiacoTaVe;
            valueselectorRitmoCardiacoTaVe = app.selectorRitmoCardiacoTaVe.Value;
            if(convertCharsToStrings(valueselectorRitmoCardiacoTaVe) == 'Entre')
                app.menorRitmoCardiacoTaVe.Visible = true;
                app.mayorRitmoCardiacoTaVe.Visible = true;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoTaVe) == 'Mayor')
                app.menorRitmoCardiacoTaVe.Visible = false;
                app.mayorRitmoCardiacoTaVe.Visible = true;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoTaVe) == 'Menor')
                app.menorRitmoCardiacoTaVe.Visible = true;
                app.mayorRitmoCardiacoTaVe.Visible = false;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoTaVe) == 'Ninguno')
                app.menorRitmoCardiacoTaVe.Visible = false;
                app.mayorRitmoCardiacoTaVe.Visible = false;
            end
        end

        % Value changed function: selectorOndaPTaVe
        function selectorOndaPTaVeValueChanged(app, event)
            global valueselectorOndaPTaVe;
            valueselectorOndaPTaVe = app.selectorOndaPTaVe.Value;
            if(convertCharsToStrings(valueselectorOndaPTaVe) == '1')
            elseif(convertCharsToStrings(valueselectorOndaPTaVe) == '0')   
            elseif(convertCharsToStrings(valueselectorOndaPTaVe) == 'Ninguno')
            end
        end

        % Value changed function: selectorRitmoRegularTaVe
        function selectorRitmoRegularTaVeValueChanged(app, event)
            global valueselectorRitmoRegularTaVe;
            valueselectorRitmoRegularTaVe = app.selectorRitmoRegularTaVe.Value;
            if(convertCharsToStrings(valueselectorRitmoRegularTaVe) == 'Regular')
            elseif(convertCharsToStrings(valueselectorRitmoRegularTaVe) == 'Irregular')
            elseif(convertCharsToStrings(valueselectorRitmoRegularTaVe) == 'Ambos')
            elseif(convertCharsToStrings(valueselectorRitmoRegularTaVe) == 'Ninguno')
            end
        end

        % Value changed function: selectorPRmsFluVe
        function selectorPRmsFluVeValueChanged(app, event)
            global valueselectorPRmsFluVe;
            valueselectorPRmsFluVe = app.selectorPRmsFluVe.Value;
            if(convertCharsToStrings(valueselectorPRmsFluVe) == 'Entre')
                app.menorPRmsFluVe.Visible = true;
                app.mayorPRmsFluVe.Visible = true;
            elseif(convertCharsToStrings(valueselectorPRmsFluVe) == 'Mayor')
                app.menorPRmsFluVe.Visible = false;
                app.mayorPRmsFluVe.Visible = true;
            elseif(convertCharsToStrings(valueselectorPRmsFluVe) == 'Menor')
                app.menorPRmsFluVe.Visible = true;
                app.mayorPRmsFluVe.Visible = false;
            elseif(convertCharsToStrings(valueselectorPRmsFluVe) == 'Ninguno')
                app.menorPRmsFluVe.Visible = false;
                app.mayorPRmsFluVe.Visible = false;
            end
        end

        % Value changed function: selectorQRSmsFluVe
        function selectorQRSmsFluVeValueChanged(app, event)
            global valueselectorQRSmsFluVe;
            valueselectorQRSmsFluVe = app.selectorQRSmsFluVe.Value;
            if(convertCharsToStrings(valueselectorQRSmsFluVe) == 'Entre')
                app.menorQRSmsFluVe.Visible = true;
                app.mayorQRSmsFluVe.Visible = true;
            elseif(convertCharsToStrings(valueselectorQRSmsFluVe) == 'Mayor')
                app.menorQRSmsFluVe.Visible = false;
                app.mayorQRSmsFluVe.Visible = true;
            elseif(convertCharsToStrings(valueselectorQRSmsFluVe) == 'Menor')
                app.menorQRSmsFluVe.Visible = true;
                app.mayorQRSmsFluVe.Visible = false;
            elseif(convertCharsToStrings(valueselectorQRSmsFluVe) == 'Ninguno')
                app.menorQRSmsFluVe.Visible = false;
                app.mayorQRSmsFluVe.Visible = false;
            end
        end

        % Value changed function: selectorRitmoCardiacoFluVe
        function selectorRitmoCardiacoFluVeValueChanged(app, event)
            global valueselectorRitmoCardiacoFluVe;
            valueselectorRitmoCardiacoFluVe = app.selectorRitmoCardiacoFluVe.Value;
            if(convertCharsToStrings(valueselectorRitmoCardiacoFluVe) == 'Entre')
                app.menorRitmoCardiacoFluVe.Visible = true;
                app.mayorRitmoCardiacoFluVe.Visible = true;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoFluVe) == 'Mayor')
                app.menorRitmoCardiacoFluVe.Visible = false;
                app.mayorRitmoCardiacoFluVe.Visible = true;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoFluVe) == 'Menor')
                app.menorRitmoCardiacoFluVe.Visible = true;
                app.mayorRitmoCardiacoFluVe.Visible = false;
            elseif(convertCharsToStrings(valueselectorRitmoCardiacoFluVe) == 'Ninguno')
                app.menorRitmoCardiacoFluVe.Visible = false;
                app.mayorRitmoCardiacoFluVe.Visible = false;
            end
        end

        % Value changed function: selectorOndaPFluVe
        function selectorOndaPFluVeValueChanged(app, event)
            global valueselectorOndaPFluVe;
            valueselectorOndaPFluVe = app.selectorOndaPFluVe.Value;
            if(convertCharsToStrings(valueselectorOndaPFluVe) == '1')
            elseif(convertCharsToStrings(valueselectorOndaPFluVe) == '0')   
            elseif(convertCharsToStrings(valueselectorOndaPFluVe) == 'Ninguno')
            end
        end

        % Value changed function: selectorRitmoRegularFluVe
        function selectorRitmoRegularFluVeValueChanged(app, event)
            global valueselectorRitmoRegularFluVe;
            valueselectorRitmoRegularFluVe = app.selectorRitmoRegularFluVe.Value;
            if(convertCharsToStrings(valueselectorRitmoRegularFluVe) == 'Regular')
            elseif(convertCharsToStrings(valueselectorRitmoRegularFluVe) == 'Irregular')
            elseif(convertCharsToStrings(valueselectorRitmoRegularFluVe) == 'Ambos')
            elseif(convertCharsToStrings(valueselectorRitmoRegularFluVe) == 'Ninguno')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RitmoSinusalNormalLabel = uilabel(app.UIFigure);
            app.RitmoSinusalNormalLabel.Text = 'Ritmo Sinusal Normal';
            app.BradicardiaSinusalLabel = uilabel(app.UIFigure);
            app.BradicardiaSinusalLabel.Text = 'Bradicardia Sinusal';
            app.FibrilacinAuricularLabel = uilabel(app.UIFigure);
            app.FibrilacinAuricularLabel.Text = 'Fibrilación Auricular';
            app.AleteoAuricularLabel = uilabel(app.UIFigure);
            app.AleteoAuricularLabel.Text = 'Aleteo Auricular';
            app.TaquicardiaVentricularLabel = uilabel(app.UIFigure);
            app.TaquicardiaVentricularLabel.Text = 'Taquicardia Ventricular';
            app.FlutterVentricularLabel = uilabel(app.UIFigure);
            app.FlutterVentricularLabel.Text = 'Flutter Ventricular';
            app.PRmsLabel = uilabel(app.UIFigure);
            app.PRmsLabel.Text = 'PRms';
            app.QRSmsLabel = uilabel(app.UIFigure);
            app.QRSmsLabel.Text = 'QRSms';
            app.RitmocardiacoLabel = uilabel(app.UIFigure);
            app.RitmocardiacoLabel.Text = 'Ritmo cardiaco';
            app.OndaPLabel = uilabel(app.UIFigure);
            app.OndaPLabel.Text = 'Onda P';
            app.RitmoregularLabel = uilabel(app.UIFigure);
            app.RitmoregularLabel.Text = 'Ritmo regular';
            app.selectorPRmsRSN = uidropdown(app.UIFigure);
            app.selectorPRmsRSN.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorPRmsRSN.ValueChangedFcn = createCallbackFcn(app, @selectorPRmsRSNValueChanged, true);
            app.selectorPRmsRSN.Value = 'Entre';
            app.menorPRmsRSN = uieditfield(app.UIFigure, 'text');
            app.mayorPRmsRSN = uieditfield(app.UIFigure, 'text');
            app.selectorQRSmsRSN = uidropdown(app.UIFigure);
            app.selectorQRSmsRSN.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorQRSmsRSN.ValueChangedFcn = createCallbackFcn(app, @selectorQRSmsRSNValueChanged, true);
            app.selectorQRSmsRSN.Value = 'Entre';
            app.menorQRSmsRSN = uieditfield(app.UIFigure, 'text');
            app.mayorQRSmsRSN = uieditfield(app.UIFigure, 'text');
            app.selectorRitmoCardiacoRSN = uidropdown(app.UIFigure);
            app.selectorRitmoCardiacoRSN.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorRitmoCardiacoRSN.ValueChangedFcn = createCallbackFcn(app, @selectorRitmoCardiacoRSNValueChanged, true);
            app.selectorRitmoCardiacoRSN.Value = 'Entre';
            app.menorRitmoCardiacoRSN = uieditfield(app.UIFigure, 'text');
            app.mayorRitmoCardiacoRSN = uieditfield(app.UIFigure, 'text');
            app.selectorOndaPRSN = uidropdown(app.UIFigure);
            app.selectorOndaPRSN.Items = {'1', '0', 'Ninguno'};
            app.selectorOndaPRSN.ValueChangedFcn = createCallbackFcn(app, @selectorOndaPRSNValueChanged, true);
            app.selectorOndaPRSN.Value = '1';
            app.selectorRitmoRegularRSN = uidropdown(app.UIFigure);
            app.selectorRitmoRegularRSN.Items = {'Regular', 'Irregular', 'Ambos', 'Ninguno'};
            app.selectorRitmoRegularRSN.ValueChangedFcn = createCallbackFcn(app, @selectorRitmoRegularRSNValueChanged, true);
            app.selectorRitmoRegularRSN.Value = 'Regular';
            app.selectorPRmsBS = uidropdown(app.UIFigure);
            app.selectorPRmsBS.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorPRmsBS.ValueChangedFcn = createCallbackFcn(app, @selectorPRmsBSValueChanged, true);
            app.selectorPRmsBS.Value = 'Entre';
            app.menorPRmsBS = uieditfield(app.UIFigure, 'text');
            app.mayorPRmsBS = uieditfield(app.UIFigure, 'text');
            app.selectorQRSmsBS = uidropdown(app.UIFigure);
            app.selectorQRSmsBS.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorQRSmsBS.ValueChangedFcn = createCallbackFcn(app, @selectorQRSmsBSValueChanged, true);
            app.selectorQRSmsBS.Value = 'Entre';
            app.menorQRSmsBS = uieditfield(app.UIFigure, 'text');
            app.mayorQRSmsBS = uieditfield(app.UIFigure, 'text');
            app.selectorRitmoCardiacoBS = uidropdown(app.UIFigure);
            app.selectorRitmoCardiacoBS.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorRitmoCardiacoBS.ValueChangedFcn = createCallbackFcn(app, @selectorRitmoCardiacoBSValueChanged, true);
            app.selectorRitmoCardiacoBS.Value = 'Entre';
            app.menorRitmoCardiacoBS = uieditfield(app.UIFigure, 'text');
            app.mayorRitmoCardiacoBS = uieditfield(app.UIFigure, 'text');
            app.selectorOndaPBS = uidropdown(app.UIFigure);
            app.selectorOndaPBS.Items = {'1', '0', 'Ninguno'};
            app.selectorOndaPBS.ValueChangedFcn = createCallbackFcn(app, @selectorOndaPBSValueChanged, true);
            app.selectorOndaPBS.Value = '1';
            app.selectorRitmoRegularBS = uidropdown(app.UIFigure);
            app.selectorRitmoRegularBS.Items = {'Regular', 'Irregular', 'Ambos', 'Ninguno'};
            app.selectorRitmoRegularBS.ValueChangedFcn = createCallbackFcn(app, @selectorRitmoRegularBSValueChanged, true);
            app.selectorRitmoRegularBS.Value = 'Regular';
            app.selectorPRmsFibAur = uidropdown(app.UIFigure);
            app.selectorPRmsFibAur.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorPRmsFibAur.ValueChangedFcn = createCallbackFcn(app, @selectorPRmsFibAurValueChanged, true);
            app.selectorPRmsFibAur.Value = 'Entre';
            app.menorPRmsFibAur = uieditfield(app.UIFigure, 'text');
            app.mayorPRmsFibAur = uieditfield(app.UIFigure, 'text');
            app.selectorQRSmsFibAur = uidropdown(app.UIFigure);
            app.selectorQRSmsFibAur.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorQRSmsFibAur.ValueChangedFcn = createCallbackFcn(app, @selectorQRSmsFibAurValueChanged, true);
            app.selectorQRSmsFibAur.Value = 'Entre';
            app.menorQRSmsFibAur = uieditfield(app.UIFigure, 'text');
            app.mayorQRSmsFibAur = uieditfield(app.UIFigure, 'text');
            app.selectorRitmoCardiacoFibAur = uidropdown(app.UIFigure);
            app.selectorRitmoCardiacoFibAur.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorRitmoCardiacoFibAur.ValueChangedFcn = createCallbackFcn(app, @selectorRitmoCardiacoFibAurValueChanged, true);
            app.selectorRitmoCardiacoFibAur.Value = 'Entre';
            app.menorRitmoCardiacoFibAur = uieditfield(app.UIFigure, 'text');
            app.mayorRitmoCardiacoFibAur = uieditfield(app.UIFigure, 'text');
            app.selectorOndaPFibAur = uidropdown(app.UIFigure);
            app.selectorOndaPFibAur.Items = {'1', '0', 'Ninguno'};
            app.selectorOndaPFibAur.ValueChangedFcn = createCallbackFcn(app, @selectorOndaPFibAurValueChanged, true);
            app.selectorOndaPFibAur.Value = '1';
            app.selectorRitmoRegularFibAur = uidropdown(app.UIFigure);
            app.selectorRitmoRegularFibAur.Items = {'Regular', 'Irregular', 'Ambos', 'Ninguno'};
            app.selectorRitmoRegularFibAur.ValueChangedFcn = createCallbackFcn(app, @selectorRitmoRegularFibAurValueChanged, true);
            app.selectorRitmoRegularFibAur.Value = 'Regular';
            app.selectorPRmsAleAur = uidropdown(app.UIFigure);
            app.selectorPRmsAleAur.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorPRmsAleAur.ValueChangedFcn = createCallbackFcn(app, @selectorPRmsAleAurValueChanged, true);
            app.selectorPRmsAleAur.Value = 'Entre';
            app.menorPRmsAleAur = uieditfield(app.UIFigure, 'text');
            app.mayorPRmsAleAur = uieditfield(app.UIFigure, 'text');
            app.selectorQRSmsAleAur = uidropdown(app.UIFigure);
            app.selectorQRSmsAleAur.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorQRSmsAleAur.ValueChangedFcn = createCallbackFcn(app, @selectorQRSmsAleAurValueChanged, true);
            app.selectorQRSmsAleAur.Value = 'Entre';
            app.menorQRSmsAleAur = uieditfield(app.UIFigure, 'text');
            app.mayorQRSmsAleAur = uieditfield(app.UIFigure, 'text');
            app.selectorRitmoCardiacoAleAur = uidropdown(app.UIFigure);
            app.selectorRitmoCardiacoAleAur.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorRitmoCardiacoAleAur.ValueChangedFcn = createCallbackFcn(app, @selectorRitmoCardiacoAleAurValueChanged, true);
            app.selectorRitmoCardiacoAleAur.Value = 'Entre';
            app.menorRitmoCardiacoAleAur = uieditfield(app.UIFigure, 'text');
            app.mayorRitmoCardiacoAleAur = uieditfield(app.UIFigure, 'text');
            app.selectorOndaPAleAur = uidropdown(app.UIFigure);
            app.selectorOndaPAleAur.Items = {'1', '0', 'Ninguno'};
            app.selectorOndaPAleAur.ValueChangedFcn = createCallbackFcn(app, @selectorOndaPAleAurValueChanged, true);
            app.selectorOndaPAleAur.Value = '1';
            app.selectorRitmoRegularAleAur = uidropdown(app.UIFigure);
            app.selectorRitmoRegularAleAur.Items = {'Regular', 'Irregular', 'Ambos', 'Ninguno'};
            app.selectorRitmoRegularAleAur.ValueChangedFcn = createCallbackFcn(app, @selectorRitmoRegularAleAurValueChanged, true);
            app.selectorRitmoRegularAleAur.Value = 'Regular';
            app.selectorPRmsTaVe = uidropdown(app.UIFigure);
            app.selectorPRmsTaVe.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorPRmsTaVe.ValueChangedFcn = createCallbackFcn(app, @selectorPRmsTaVeValueChanged, true);
            app.selectorPRmsTaVe.Value = 'Entre';
            app.menorPRmsTaVe = uieditfield(app.UIFigure, 'text');
            app.mayorPRmsTaVe = uieditfield(app.UIFigure, 'text');
            app.selectorQRSmsTaVe = uidropdown(app.UIFigure);
            app.selectorQRSmsTaVe.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorQRSmsTaVe.ValueChangedFcn = createCallbackFcn(app, @selectorQRSmsTaVeValueChanged, true);
            app.selectorQRSmsTaVe.Value = 'Entre';
            app.menorQRSmsTaVe = uieditfield(app.UIFigure, 'text');
            app.mayorQRSmsTaVe = uieditfield(app.UIFigure, 'text');
            app.selectorRitmoCardiacoTaVe = uidropdown(app.UIFigure);
            app.selectorRitmoCardiacoTaVe.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorRitmoCardiacoTaVe.ValueChangedFcn = createCallbackFcn(app, @selectorRitmoCardiacoTaVeValueChanged, true);
            app.selectorRitmoCardiacoTaVe.Value = 'Entre';
            app.menorRitmoCardiacoTaVe = uieditfield(app.UIFigure, 'text');
            app.mayorRitmoCardiacoTaVe = uieditfield(app.UIFigure, 'text');
            app.selectorOndaPTaVe = uidropdown(app.UIFigure);
            app.selectorOndaPTaVe.Items = {'1', '0', 'Ninguno'};
            app.selectorOndaPTaVe.ValueChangedFcn = createCallbackFcn(app, @selectorOndaPTaVeValueChanged, true);
            app.selectorOndaPTaVe.Value = '1';
            app.selectorRitmoRegularTaVe = uidropdown(app.UIFigure);
            app.selectorRitmoRegularTaVe.Items = {'Regular', 'Irregular', 'Ambos', 'Ninguno'};
            app.selectorRitmoRegularTaVe.ValueChangedFcn = createCallbackFcn(app, @selectorRitmoRegularTaVeValueChanged, true);
            app.selectorRitmoRegularTaVe.Value = 'Regular';
            app.selectorPRmsFluVe = uidropdown(app.UIFigure);
            app.selectorPRmsFluVe.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorPRmsFluVe.ValueChangedFcn = createCallbackFcn(app, @selectorPRmsFluVeValueChanged, true);
            app.selectorPRmsFluVe.Value = 'Entre';
            app.menorPRmsFluVe = uieditfield(app.UIFigure, 'text');
            app.mayorPRmsFluVe = uieditfield(app.UIFigure, 'text');
            app.selectorQRSmsFluVe = uidropdown(app.UIFigure);
            app.selectorQRSmsFluVe.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorQRSmsFluVe.ValueChangedFcn = createCallbackFcn(app, @selectorQRSmsFluVeValueChanged, true);
            app.selectorQRSmsFluVe.Value = 'Entre';
            app.menorQRSmsFluVe = uieditfield(app.UIFigure, 'text');
            app.mayorQRSmsFluVe = uieditfield(app.UIFigure, 'text');
            app.selectorRitmoCardiacoFluVe = uidropdown(app.UIFigure);
            app.selectorRitmoCardiacoFluVe.Items = {'Entre', 'Mayor', 'Menor', 'Ninguno'};
            app.selectorRitmoCardiacoFluVe.ValueChangedFcn = createCallbackFcn(app, @selectorRitmoCardiacoFluVeValueChanged, true);
            app.selectorRitmoCardiacoFluVe.Value = 'Entre';
            app.menorRitmoCardiacoFluVe = uieditfield(app.UIFigure, 'text');
            app.mayorRitmoCardiacoFluVe = uieditfield(app.UIFigure, 'text');
            app.selectorOndaPFluVe = uidropdown(app.UIFigure);
            app.selectorOndaPFluVe.Items = {'1', '0', 'Ninguno'};
            app.selectorOndaPFluVe.ValueChangedFcn = createCallbackFcn(app, @selectorOndaPFluVeValueChanged, true);
            app.selectorOndaPFluVe.Value = '1';
            app.selectorRitmoRegularFluVe = uidropdown(app.UIFigure);
            app.selectorRitmoRegularFluVe.Items = {'Regular', 'Irregular', 'Ambos', 'Ninguno'};
            app.selectorRitmoRegularFluVe.ValueChangedFcn = createCallbackFcn(app, @selectorRitmoRegularFluVeValueChanged, true);
            app.selectorRitmoRegularFluVe.Value = 'Regular';
            app.AnalizarButton = uibutton(app.UIFigure, 'push');
            app.AnalizarButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizarButtonPushed, true);
            app.AnalizarButton.Text = 'Analizar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResultadosArritmias

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end