classdef ResultadosArritmiasSinCondicionalesGraficos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UITable                      matlab.ui.control.Table
        UITable_2                    matlab.ui.control.Table
        UITable_3                    matlab.ui.control.Table
        UITable_4                    matlab.ui.control.Table
        UITable_5                    matlab.ui.control.Table
        UITable_6                    matlab.ui.control.Table
        RitmoSinusalNormalLabel      matlab.ui.control.Label
        BradicardiaSinusalLabel      matlab.ui.control.Label
        FibrilacinAuricularLabel     matlab.ui.control.Label
        AleteoAuricularLabel         matlab.ui.control.Label
        TaquicardiaventricularLabel  matlab.ui.control.Label
        UITable_7                    matlab.ui.control.Table
        FlutterventricularLabel      matlab.ui.control.Label
        OtraArritmiaLabel            matlab.ui.control.Label
        AnalizarButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnalizarButton
        function AnalizarButtonPushed(app, event)
            disp('Analizar boton');
            load(strcat('ResultadosAnotacion/','resultadosTodo'));
            load(strcat('ResultadosAnotacion/','resultadosTodoConNumero'));
            assignin('base','resultadosTodo',resultadosTodo);
            assignin('base','resultadosTodoConNumero',resultadosTodoConNumero);
            contRSN = 1;
            NSyR=[];
            SyBr=[];
            AtFl=[];
            AtFib=[];
            VTa=[];
            VFl=[];
            OtraArritmia=[];
            Resultados=[];
            for i=1:length(resultadosTodoConNumero)
                %PRms
                if(resultadosTodoConNumero{i,5}>120&&resultadosTodoConNumero{i,5}<=200)
                    PRint=1;
                elseif(resultadosTodoConNumero{i,5}>200)
                    PRint=2;
                else
                    PRint=0;
                end
                %QRS
                if(resultadosTodoConNumero{i,6}<120)
                    normalqrs=1;
                else
                    normalqrs=0;
                end
                if(resultadosTodoConNumero{i,7}<60)
                    latnormal=0;
                    taqui=0;
                    bradi=1;
                elseif(resultadosTodoConNumero{i,7}>100)
                    latnormal=0;
                    taqui=1;
                    bradi=0;
                elseif(resultadosTodoConNumero{i,7} >=60 && resultadosTodoConNumero{i,7} <= 100)
                    latnormal=1;
                    taqui=0;
                    bradi=0;
                end
                
                if(resultadosTodoConNumero{i,7} >=120)
                    HRalto=1;
                else
                    HRalto=0;
                end
                
                if(resultadosTodoConNumero{i,8} == 1)
                    hayOndaP=1;
                else
                    hayOndaP=0;
                end
                if(resultadosTodoConNumero{i,9}<= 0.080)
                    RitmoR=1;
                else
                    RitmoR=0;
                end
                if PRint && normalqrs && latnormal && hayOndaP
                    NSyR=[NSyR; resultadosTodoConNumero{i,1} resultadosTodoConNumero{i,2} resultadosTodoConNumero{i,3} resultadosTodoConNumero{i,4} resultadosTodoConNumero{i,5} resultadosTodoConNumero{i,6} resultadosTodoConNumero{i,7} resultadosTodoConNumero{i,8} resultadosTodoConNumero{i,9} resultadosTodoConNumero{i,10} {'(N'}];
                    Resultados=[Resultados; resultadosTodoConNumero{i,1} resultadosTodoConNumero{i,2} resultadosTodoConNumero{i,3} resultadosTodoConNumero{i,4} resultadosTodoConNumero{i,5} resultadosTodoConNumero{i,6} resultadosTodoConNumero{i,7} resultadosTodoConNumero{i,8} resultadosTodoConNumero{i,9} resultadosTodoConNumero{i,10} {'(N'}];
                elseif bradi && PRint
                    SyBr=[SyBr; resultadosTodoConNumero{i,1} resultadosTodoConNumero{i,2} resultadosTodoConNumero{i,3} resultadosTodoConNumero{i,4} resultadosTodoConNumero{i,5} resultadosTodoConNumero{i,6} resultadosTodoConNumero{i,7} resultadosTodoConNumero{i,8} resultadosTodoConNumero{i,9} resultadosTodoConNumero{i,10} {'(SBR'}];
                    Resultados=[Resultados; resultadosTodoConNumero{i,1} resultadosTodoConNumero{i,2} resultadosTodoConNumero{i,3} resultadosTodoConNumero{i,4} resultadosTodoConNumero{i,5} resultadosTodoConNumero{i,6} resultadosTodoConNumero{i,7} resultadosTodoConNumero{i,8} resultadosTodoConNumero{i,9} resultadosTodoConNumero{i,10} {'(SBR'}];
                elseif ~RitmoR &&  normalqrs
                    AtFib=[AtFib; resultadosTodoConNumero{i,1} resultadosTodoConNumero{i,2} resultadosTodoConNumero{i,3} resultadosTodoConNumero{i,4} resultadosTodoConNumero{i,5} resultadosTodoConNumero{i,6} resultadosTodoConNumero{i,7} resultadosTodoConNumero{i,8} resultadosTodoConNumero{i,9} resultadosTodoConNumero{i,10} {'(AFIB'}];
                    Resultados=[Resultados; resultadosTodoConNumero{i,1} resultadosTodoConNumero{i,2} resultadosTodoConNumero{i,3} resultadosTodoConNumero{i,4} resultadosTodoConNumero{i,5} resultadosTodoConNumero{i,6} resultadosTodoConNumero{i,7} resultadosTodoConNumero{i,8} resultadosTodoConNumero{i,9} resultadosTodoConNumero{i,10} {'(AFIB'}];
                elseif(RitmoR && normalqrs)
                    AtFl =[AtFl; resultadosTodoConNumero{i,1} resultadosTodoConNumero{i,2} resultadosTodoConNumero{i,3} resultadosTodoConNumero{i,4} resultadosTodoConNumero{i,5} resultadosTodoConNumero{i,6} resultadosTodoConNumero{i,7} resultadosTodoConNumero{i,8} resultadosTodoConNumero{i,9} resultadosTodoConNumero{i,10} {'(AFL'}];
                    Resultados=[Resultados; resultadosTodoConNumero{i,1} resultadosTodoConNumero{i,2} resultadosTodoConNumero{i,3} resultadosTodoConNumero{i,4} resultadosTodoConNumero{i,5} resultadosTodoConNumero{i,6} resultadosTodoConNumero{i,7} resultadosTodoConNumero{i,8} resultadosTodoConNumero{i,9} resultadosTodoConNumero{i,10} {'(AFL'}];
                elseif RitmoR && taqui && ~normalqrs
                    VTa=[VTa; resultadosTodoConNumero{i,1} resultadosTodoConNumero{i,2} resultadosTodoConNumero{i,3} resultadosTodoConNumero{i,4} resultadosTodoConNumero{i,5} resultadosTodoConNumero{i,6} resultadosTodoConNumero{i,7} resultadosTodoConNumero{i,8} resultadosTodoConNumero{i,9} resultadosTodoConNumero{i,10} {'(VT'}];
                    Resultados=[Resultados; resultadosTodoConNumero{i,1} resultadosTodoConNumero{i,2} resultadosTodoConNumero{i,3} resultadosTodoConNumero{i,4} resultadosTodoConNumero{i,5} resultadosTodoConNumero{i,6} resultadosTodoConNumero{i,7} resultadosTodoConNumero{i,8} resultadosTodoConNumero{i,9} resultadosTodoConNumero{i,10} {'(VT'}];
                elseif(~RitmoR && HRalto && ~normalqrs && ~hayOndaP)
                    VFl=[VFl; resultadosTodoConNumero{i,1} resultadosTodoConNumero{i,2} resultadosTodoConNumero{i,3} resultadosTodoConNumero{i,4} resultadosTodoConNumero{i,5} resultadosTodoConNumero{i,6} resultadosTodoConNumero{i,7} resultadosTodoConNumero{i,8} resultadosTodoConNumero{i,9} resultadosTodoConNumero{i,10} {'(VFL'}];
                    Resultados=[Resultados; resultadosTodoConNumero{i,1} resultadosTodoConNumero{i,2} resultadosTodoConNumero{i,3} resultadosTodoConNumero{i,4} resultadosTodoConNumero{i,5} resultadosTodoConNumero{i,6} resultadosTodoConNumero{i,7} resultadosTodoConNumero{i,8} resultadosTodoConNumero{i,9} resultadosTodoConNumero{i,10} {'(VFL'}];
                else
                    OtraArritmia=[OtraArritmia; resultadosTodoConNumero{i,1} resultadosTodoConNumero{i,2} resultadosTodoConNumero{i,3} resultadosTodoConNumero{i,4} resultadosTodoConNumero{i,5} resultadosTodoConNumero{i,6} resultadosTodoConNumero{i,7} resultadosTodoConNumero{i,8} resultadosTodoConNumero{i,9} resultadosTodoConNumero{i,10} {'(OARR'}];
                    Resultados=[Resultados; resultadosTodoConNumero{i,1} resultadosTodoConNumero{i,2} resultadosTodoConNumero{i,3} resultadosTodoConNumero{i,4} resultadosTodoConNumero{i,5} resultadosTodoConNumero{i,6} resultadosTodoConNumero{i,7} resultadosTodoConNumero{i,8} resultadosTodoConNumero{i,9} resultadosTodoConNumero{i,10} {'(OARR'}];
                end
            end
            assignin('base','NSyR',NSyR);
            assignin('base','SyBr',SyBr);
            assignin('base','AtFib',AtFib);
            assignin('base','VTa',VTa);
            assignin('base','VFl',VFl);
            assignin('base','OtraArritmia',OtraArritmia);
            assignin('base','Resultados',Resultados);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.UITable_3 = uitable(app.UIFigure);
            app.UITable_3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_3.RowName = {};
            app.UITable_4 = uitable(app.UIFigure);
            app.UITable_4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_4.RowName = {};
            app.UITable_5 = uitable(app.UIFigure);
            app.UITable_5.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_5.RowName = {};
            app.UITable_6 = uitable(app.UIFigure);
            app.UITable_6.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_6.RowName = {};
            app.RitmoSinusalNormalLabel = uilabel(app.UIFigure);
            app.RitmoSinusalNormalLabel.Text = 'Ritmo Sinusal Normal';
            app.BradicardiaSinusalLabel = uilabel(app.UIFigure);
            app.BradicardiaSinusalLabel.Text = 'Bradicardia Sinusal';
            app.FibrilacinAuricularLabel = uilabel(app.UIFigure);
            app.FibrilacinAuricularLabel.Text = 'Fibrilación Auricular';
            app.AleteoAuricularLabel = uilabel(app.UIFigure);
            app.AleteoAuricularLabel.Text = 'Aleteo Auricular';
            app.TaquicardiaventricularLabel = uilabel(app.UIFigure);
            app.TaquicardiaventricularLabel.Text = 'Taquicardia ventricular';
            app.UITable_7 = uitable(app.UIFigure);
            app.UITable_7.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_7.RowName = {};
            app.FlutterventricularLabel = uilabel(app.UIFigure);
            app.FlutterventricularLabel.Text = 'Flutter ventricular';
            app.OtraArritmiaLabel = uilabel(app.UIFigure);
            app.OtraArritmiaLabel.Text = 'Otra Arritmia';
            app.AnalizarButton = uibutton(app.UIFigure, 'push');
            app.AnalizarButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizarButtonPushed, true);
            app.AnalizarButton.Text = 'Analizar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResultadosArritmiasSinCondicionalesGraficos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end